classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        q1_targetEditFieldLabel  matlab.ui.control.Label
        q1_targetEditField       matlab.ui.control.NumericEditField
        w1_0EditFieldLabel       matlab.ui.control.Label
        w1_0EditField            matlab.ui.control.NumericEditField
        q2_targetEditFieldLabel  matlab.ui.control.Label
        q2_targetEditField       matlab.ui.control.NumericEditField
        w2_0EditFieldLabel       matlab.ui.control.Label
        w2_0EditField            matlab.ui.control.NumericEditField
        w1_1EditFieldLabel       matlab.ui.control.Label
        w1_1EditField            matlab.ui.control.NumericEditField
        w2_1EditFieldLabel       matlab.ui.control.Label
        w2_1EditField            matlab.ui.control.NumericEditField
        StartButton              matlab.ui.control.Button
        L1EditFieldLabel         matlab.ui.control.Label
        L1EditField              matlab.ui.control.NumericEditField
        L2EditFieldLabel         matlab.ui.control.Label
        L2EditField              matlab.ui.control.NumericEditField
        m1EditFieldLabel         matlab.ui.control.Label
        m1EditField              matlab.ui.control.NumericEditField
        m2EditFieldLabel         matlab.ui.control.Label
        m2EditField              matlab.ui.control.NumericEditField
        TEditFieldLabel          matlab.ui.control.Label
        TEditField               matlab.ui.control.NumericEditField
        ClearTrajectoryButton    matlab.ui.control.Button
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes2_2                matlab.ui.control.UIAxes
        UIAxes2_3                matlab.ui.control.UIAxes
        UIAxes4                  matlab.ui.control.UIAxes
        UIAxes4_2                matlab.ui.control.UIAxes
        e1_0EditFieldLabel       matlab.ui.control.Label
        e1_0EditField            matlab.ui.control.NumericEditField
        e1_1EditFieldLabel       matlab.ui.control.Label
        e1_1EditField            matlab.ui.control.NumericEditField
        e2_0EditFieldLabel       matlab.ui.control.Label
        e2_0EditField            matlab.ui.control.NumericEditField
        e2_1EditFieldLabel       matlab.ui.control.Label
        e2_1EditField            matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        L1 = 1;
        L2 = 1;
        m1 = 1;
        m2 = 1;
        g = 9.81;
        dt = 0.02;
        
        q1_0 = 0;    
        w1_0 = 0;
        w1_1 = 0;
        e1_0 = 0;
        e1_1 = 0;
        
        q2_0 = 0;    
        w2_0 = 0;
        w2_1 = 0;
        e2_0 = 0;
        e2_1 = 0;

        
        t0 = 0;
        t1 = 0;
        
        q1_target = 10*(pi/180);
        q2_target = 20*(pi/180);
        
        T = 1;
        
%         a1 = 0; b1 = 0; c1 = 0; d1 = 0; f1 = 0; g1 = 0;
%         a2 = 0; b2 = 0; c2 = 0; d2 = 0; f2 = 0; g2 = 0;
        
        h; % leg animatedline
        tr; % leg trajectory
        q1_graph;
        q2_graph;
        w1_graph;
        w2_graph;
        e1_graph;
        e2_graph;
        tau1_graph;
        tau2_graph;
        
    end
    
    methods (Access = private)
        
        function [a,  b,  c,  d] = CIP(~,q_0,q_1, w_0, w_1, T)
        %CUBIC_INTERPOLATING_POLYNOMIAL 
        %   Returns coefficients of cubic polynomial
            a = q_0;
            b = w_0;
            c = ( 3*(q_1-q_0)- T*(2*w_0+w_1) ) / T^2;
            d = ( 2*(q_0-q_1) - T*(w_0+w_1) )/ T^3; 
        end

        
        function ret = Poli5(~, q_0, q_1, w_0, w_1, e_0, e_1, T)
           
        A = [   1 0 0 0 0 0;
                0 1 0 0 0 0;
                0 0 2 0 0 0;
                1 T T^2 T^3 T^4 T^5;
                0 1 2*T 3*T^2 4*T^3 5*T^4;
                0 0 2 6*T 12*T^2 20*T^3];
        
        ret = A \ [q_0, w_0, e_0, q_1, w_1, e_1]';
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes, [-2 2 -2 2]);
            app.L1EditField.Value = app.L1;
            app.L2EditField.Value = app.L2;
            app.m1EditField.Value = app.m1;
            app.m2EditField.Value = app.m2;
            app.q1_targetEditField.Value = app.q1_target*(180/pi);
            app.q2_targetEditField.Value = app.q2_target*(180/pi);            
            app.w1_0EditField.Value = app.w1_0;       
            app.w1_1EditField.Value = app.w1_1;  
            app.w2_0EditField.Value = app.w2_0;       
            app.w2_1EditField.Value = app.w2_1;        
            app.TEditField.Value = app.T; 
            
            app.h = animatedline(0, 0, 'Marker', 'o', 'LineWidth', 2, 'Parent', app.UIAxes);
            
%             app.UIFigure.AutoResizeChildren = 'off';
%             ax1 = subplot(1,2,1,'Parent',app.UIFigure);
%             ax2 = subplot(1,2,2,'Parent',app.UIFigure);

            app.q1_graph = animatedline(app.UIAxes2); 
            
            app.q2_graph = animatedline(app.UIAxes2); set(app.q2_graph, 'Color', 'red');
            app.w1_graph = animatedline(app.UIAxes2_2);
            app.w2_graph = animatedline(app.UIAxes2_2); set(app.w2_graph, 'Color', 'red');
            app.e1_graph = animatedline(app.UIAxes2_3);
            app.e2_graph = animatedline(app.UIAxes2_3);set(app.e2_graph, 'Color', 'red');
            app.tau1_graph = animatedline(app.UIAxes4);
            app.tau2_graph = animatedline(app.UIAxes4_2);
                
            
                %% Forward Kinematics
            A1 = [
                    cos(app.q1_0)  -sin(app.q1_0)  0   app.L1*cos(app.q1_0);
                    sin(app.q1_0)    cos(app.q1_0)   0   app.L1*sin(app.q1_0);
                    0                   0                   1   0;
                    0                   0                   0   1];
    
    
            A2 = [
                    cos(app.q2_0)  -sin(app.q2_0)  0   app.L2*cos(app.q2_0);
                    sin(app.q2_0)  cos(app.q2_0)   0   app.L2*sin(app.q2_0);
                    0       0          1   0;
                    0       0          0   1
                    ];
    
            T1 = A1;
            T2 = A1*A2;
            
            r1 = tform2trvec(T1);
            r2 = tform2trvec(T2);   
            
            app.tr = animatedline(r2(1), r2(2), 'Color', 'red', 'Parent', app.UIAxes);
            
            addpoints(app.h, 0, 0, 0);
            addpoints(app.h, r1(1), r1(2), r1(3));
            addpoints(app.h, r2(1), r2(2), r2(3));

            drawnow;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            app.StartButton.Enable = false;
            
            if (app.q1_target == app.q1_0 && app.q2_target == app.q2_0)
                app.StartButton.Enable = true;
                return;
            end
            
            clearpoints(app.h);

%             [a1, b1, c1, d1] = CIP(app, app.q1_0, app.q1_target, app.w1_0, app.w1_1, app.T);
%             [a2, b2, c2, d2] = CIP(app, app.q2_0, app.q2_target, app.w2_0, app.w2_1, app.T);
            
            poli5q1 = Poli5(app, app.q1_0, app.q1_target, app.w1_0, app.w1_1, app.e1_0, app.e1_1, app.T);
            poli5q2 = Poli5(app, app.q2_0, app.q2_target, app.w2_0, app.w2_1, app.e2_0, app.e2_1, app.T);
            
            a1 = poli5q1(1); b1 = poli5q1(2); c1 = poli5q1(3); d1 = poli5q1(4); f1 = poli5q1(5); g1 = poli5q1(6);
            a2 = poli5q2(1); b2 = poli5q2(2); c2 = poli5q2(3); d2 = poli5q2(4); f2 = poli5q2(5); g2 = poli5q2(6);
 
            app.t1 = app.t0 + app.T;
    
            for t = app.t0:app.dt:app.t1
        
        
%                 q1 = a1 + (t-app.t0)*b1 + (t-app.t0)^2*c1 + (t-app.t0)^3*d1;
%                 q2 = a2 + (t-app.t0)*b2 + (t-app.t0)^2*c2 + (t-app.t0)^3*d2;
%         
%                 w1 = b1 + 2*(t-app.t0)*c1+3*(t-app.t0)^2*d1;
%                 w2 = b2 + 2*(t-app.t0)*c2+3*(t-app.t0)^2*d2;
%         
%                 e1 = 2*c1 + 6*(t-app.t0)*d1;
%                 e2 = 2*c2 + 6*(t-app.t0)*d2;
                q1 = a1 + (t-app.t0)*b1 + (t-app.t0)^2*c1 + (t-app.t0)^3*d1 + (t-app.t0)^4*f1 + (t-app.t0)^5*g1;
                q2 = a2 + (t-app.t0)*b2 + (t-app.t0)^2*c2 + (t-app.t0)^3*d2 + (t-app.t0)^4*f2 + (t-app.t0)^5*g2;
                
                w1 = b1 + 2*(t-app.t0)*c1+3*(t-app.t0)^2*d1 + 4*(t-app.t0)^3*f1 + 5*(t-app.t0)^4*g1;
                w2 = b2 + 2*(t-app.t0)*c2+3*(t-app.t0)^2*d2 + 4*(t-app.t0)^3*f2 + 5*(t-app.t0)^4*g2;
        
                e1 = 2*c1 + 6*(t-app.t0)*d1 + 12*(t-app.t0)^2*f1 + 20*(t-app.t0)^3*g1;
                e2 = 2*c2 + 6*(t-app.t0)*d2 + 12*(t-app.t0)^2*f2 + 20*(t-app.t0)^3*g2;                  
                
        
                tau1 = ((app.m1+app.m2)*app.L1^2 + app.m2*app.L2^2 + 2*app.m2*app.L1*app.L2*cos(q2))*e1 + (app.m2*app.L2^2+app.m2*app.L1*app.L2*cos(q2))*e2 - app.m2*app.L1*app.L2*(2*w1*w2 + w2^2)*sin(q2) + (app.m1+app.m2)*app.g*app.L1*cos(q1) + app.m2*app.g*app.L2*cos(q1+q2);
                tau2 = (app.m2*app.L2^2 + app.m2*app.L1*app.L2*cos(q2))*e1 + app.m2*app.L2^2*e2 + app.m2*app.L1*app.L2*w1^2*sin(q2) + app.m2*app.g*app.L2*cos(q1+q2);
        
        
                %% Forward Kinematics
                A1 = [
                        cos(q1)  -sin(q1)  0   app.L1*cos(q1);
                        sin(q1)  cos(q1)   0   app.L1*sin(q1);
                        0       0          1   0;
                        0       0          0   1];
        
        
                A2 = [
                        cos(q2)  -sin(q2)  0   app.L2*cos(q2);
                        sin(q2)  cos(q2)   0   app.L2*sin(q2);
                        0       0          1   0;
                        0       0          0   1
                        ];
        
                T1 = A1;
                T2 = A1*A2;
                
                r1 = tform2trvec(T1);
                r2 = tform2trvec(T2);
        
                %% Animation
        
                addpoints(app.h, 0, 0, 0);
                addpoints(app.h, r1(1), r1(2), r1(3));
                addpoints(app.h, r2(1), r2(2), r2(3));
                addpoints(app.tr, r2(1), r2(2), r2(3));
        
        
                drawnow %limitrate;
                clearpoints(app.h);
        
                addpoints(app.q1_graph, t, q1*(180/pi));
                addpoints(app.q2_graph, t, q2*(180/pi));
                addpoints(app.w1_graph, t, w1*(180/pi));
                addpoints(app.w2_graph, t, w2*(180/pi));
                addpoints(app.e1_graph, t, e1*(180/pi));
                addpoints(app.e2_graph, t, e2*(180/pi));
                addpoints(app.tau1_graph, t, tau1);
                addpoints(app.tau2_graph, t, tau2);
        
        
            end
        
            addpoints(app.h, 0, 0, 0);
            addpoints(app.h, r1(1), r1(2), r1(3));
            addpoints(app.h, r2(1), r2(2), r2(3));
        
            drawnow;
            
            app.t0 = app.t1;
            app.q1_0 = app.q1_target;
            app.q2_0 = app.q2_target;
    
            app.StartButton.Enable = true;
            
        end

        % Value changed function: L1EditField
        function L1EditFieldValueChanged(app, event)
            app.L1 = app.L1EditField.Value;
        end

        % Value changed function: L2EditField
        function L2EditFieldValueChanged(app, event)
            app.L2 = app.L2EditField.Value;            
        end

        % Value changed function: m1EditField
        function m1EditFieldValueChanged(app, event)
            app.m1 = app.m1EditField.Value;            
        end

        % Value changed function: m2EditField
        function m2EditFieldValueChanged(app, event)
            app.m2 = app.m2EditField.Value;            
        end

        % Value changed function: q1_targetEditField
        function q1_targetEditFieldValueChanged(app, event)
            app.q1_target = app.q1_targetEditField.Value*(pi/180);
            
        end

        % Value changed function: w1_0EditField
        function w1_0EditFieldValueChanged(app, event)
            app.w1_0 = app.w1_0EditField.Value;
            
        end

        % Value changed function: w1_1EditField
        function w1_1EditFieldValueChanged(app, event)
            app.w1_1 = app.w1_1EditField.Value;
            
        end

        % Value changed function: q2_targetEditField
        function q2_targetEditFieldValueChanged(app, event)
            app.q2_target = app.q2_targetEditField.Value*(pi/180);
            
        end

        % Value changed function: w2_0EditField
        function w2_0EditFieldValueChanged(app, event)
            app.w2_0 = app.w2_0EditField.Value;
            
        end

        % Value changed function: w2_1EditField
        function w2_1EditFieldValueChanged(app, event)
            app.w2_1 = app.w2_1EditField.Value;
            
        end

        % Value changed function: TEditField
        function TEditFieldValueChanged(app, event)
            app.T = app.TEditField.Value;
            
        end

        % Button pushed function: ClearTrajectoryButton
        function ClearTrajectoryButtonPushed(app, event)
            clearpoints(app.tr);
        end

        % Value changed function: e1_0EditField
        function e1_0EditFieldValueChanged(app, event)
            app.e1_0 = app.e1_0EditField.Value;
            
        end

        % Value changed function: e1_1EditField
        function e1_1EditFieldValueChanged(app, event)
            app.e1_1 = app.e1_1EditField.Value;
            
        end

        % Value changed function: e2_0EditField
        function e2_0EditFieldValueChanged(app, event)
            app.e2_0 = app.e2_0EditField.Value;
            
        end

        % Value changed function: e2_1EditField
        function e2_1EditFieldValueChanged(app, event)
            app.e2_1 = app.e2_1EditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Arm RR')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            app.q1_targetEditFieldLabel = uilabel(app.UIFigure);
            app.q1_targetEditFieldLabel.Text = 'q1_target';
            app.q1_targetEditField = uieditfield(app.UIFigure, 'numeric');
            app.q1_targetEditField.ValueChangedFcn = createCallbackFcn(app, @q1_targetEditFieldValueChanged, true);
            app.w1_0EditFieldLabel = uilabel(app.UIFigure);
            app.w1_0EditFieldLabel.Text = 'w1_0';
            app.w1_0EditField = uieditfield(app.UIFigure, 'numeric');
            app.w1_0EditField.ValueChangedFcn = createCallbackFcn(app, @w1_0EditFieldValueChanged, true);
            app.q2_targetEditFieldLabel = uilabel(app.UIFigure);
            app.q2_targetEditFieldLabel.Text = 'q2_target';
            app.q2_targetEditField = uieditfield(app.UIFigure, 'numeric');
            app.q2_targetEditField.ValueChangedFcn = createCallbackFcn(app, @q2_targetEditFieldValueChanged, true);
            app.w2_0EditFieldLabel = uilabel(app.UIFigure);
            app.w2_0EditFieldLabel.Text = 'w2_0';
            app.w2_0EditField = uieditfield(app.UIFigure, 'numeric');
            app.w2_0EditField.ValueChangedFcn = createCallbackFcn(app, @w2_0EditFieldValueChanged, true);
            app.w1_1EditFieldLabel = uilabel(app.UIFigure);
            app.w1_1EditFieldLabel.Text = 'w1_1';
            app.w1_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.w1_1EditField.ValueChangedFcn = createCallbackFcn(app, @w1_1EditFieldValueChanged, true);
            app.w2_1EditFieldLabel = uilabel(app.UIFigure);
            app.w2_1EditFieldLabel.Text = 'w2_1';
            app.w2_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.w2_1EditField.ValueChangedFcn = createCallbackFcn(app, @w2_1EditFieldValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.L1EditFieldLabel = uilabel(app.UIFigure);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.UIFigure, 'numeric');
            app.L1EditField.ValueChangedFcn = createCallbackFcn(app, @L1EditFieldValueChanged, true);
            app.L2EditFieldLabel = uilabel(app.UIFigure);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.UIFigure, 'numeric');
            app.L2EditField.ValueChangedFcn = createCallbackFcn(app, @L2EditFieldValueChanged, true);
            app.m1EditFieldLabel = uilabel(app.UIFigure);
            app.m1EditFieldLabel.Text = 'm1';
            app.m1EditField = uieditfield(app.UIFigure, 'numeric');
            app.m1EditField.ValueChangedFcn = createCallbackFcn(app, @m1EditFieldValueChanged, true);
            app.m2EditFieldLabel = uilabel(app.UIFigure);
            app.m2EditFieldLabel.Text = 'm2';
            app.m2EditField = uieditfield(app.UIFigure, 'numeric');
            app.m2EditField.ValueChangedFcn = createCallbackFcn(app, @m2EditFieldValueChanged, true);
            app.TEditFieldLabel = uilabel(app.UIFigure);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.UIFigure, 'numeric');
            app.TEditField.ValueChangedFcn = createCallbackFcn(app, @TEditFieldValueChanged, true);
            app.ClearTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.ClearTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTrajectoryButtonPushed, true);
            app.ClearTrajectoryButton.Text = 'Clear Trajectory';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Positions')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'q1/q2 (deg)')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Velocities')
            xlabel(app.UIAxes2_2, 'Time (s)')
            ylabel(app.UIAxes2_2, 'w1/w2 (rad/s)')
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            title(app.UIAxes2_3, 'Accelerations')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'e1/e2 (rad/s^2)')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Tau 1')
            xlabel(app.UIAxes4, 'Time (s)')
            ylabel(app.UIAxes4, 'H*m')
            app.UIAxes4_2 = uiaxes(app.UIFigure);
            title(app.UIAxes4_2, 'Tau 2')
            xlabel(app.UIAxes4_2, 'Time (s)')
            ylabel(app.UIAxes4_2, 'H*m')
            app.e1_0EditFieldLabel = uilabel(app.UIFigure);
            app.e1_0EditFieldLabel.Text = 'e1_0';
            app.e1_0EditField = uieditfield(app.UIFigure, 'numeric');
            app.e1_0EditField.ValueChangedFcn = createCallbackFcn(app, @e1_0EditFieldValueChanged, true);
            app.e1_1EditFieldLabel = uilabel(app.UIFigure);
            app.e1_1EditFieldLabel.Text = 'e1_1';
            app.e1_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.e1_1EditField.ValueChangedFcn = createCallbackFcn(app, @e1_1EditFieldValueChanged, true);
            app.e2_0EditFieldLabel = uilabel(app.UIFigure);
            app.e2_0EditFieldLabel.Text = 'e2_0';
            app.e2_0EditField = uieditfield(app.UIFigure, 'numeric');
            app.e2_0EditField.ValueChangedFcn = createCallbackFcn(app, @e2_0EditFieldValueChanged, true);
            app.e2_1EditFieldLabel = uilabel(app.UIFigure);
            app.e2_1EditFieldLabel.Text = 'e2_1';
            app.e2_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.e2_1EditField.ValueChangedFcn = createCallbackFcn(app, @e2_1EditFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end